classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DisplayResult                matlab.ui.control.Table
        userGuideLabel               matlab.ui.control.Label
        testLabel                    matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        FlowrateQEditFieldLabel      matlab.ui.control.Label
        FlowrateQEditField           matlab.ui.control.NumericEditField
        ViscosityLabel               matlab.ui.control.Label
        ViscosityDropDown            matlab.ui.control.DropDown
        PressurePLabel               matlab.ui.control.Label
        PressurePDropDown            matlab.ui.control.DropDown
        RPMDropDownLabel             matlab.ui.control.Label
        RPMDropDown                  matlab.ui.control.DropDown
        FlowrateVariationEditField   matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        ViscosityVariationEditField  matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        PressureVariationEditField   matlab.ui.control.NumericEditField
        Label_3                      matlab.ui.control.Label
        dropdownmodeSwitch           matlab.ui.control.Switch
        dropdownmodeSwitchLabel      matlab.ui.control.Label
        TextFieldPanel               matlab.ui.container.Panel
        ViscosityLabel_2             matlab.ui.control.Label
        ViscosityEditField           matlab.ui.control.NumericEditField
        PressurePEditFieldLabel      matlab.ui.control.Label
        PressurePEditField           matlab.ui.control.NumericEditField
        TotalresultsLabel            matlab.ui.control.Label
        mhrLabel                     matlab.ui.control.Label
        cStLabel                     matlab.ui.control.Label
        barLabel                     matlab.ui.control.Label
    end


    properties (Access = private)
        data = load('data_sheet_all_mod.mat');
        currentFlowrateData 
        currentViscocityData
        currentPrassureData
        flowrate
        viscosity
        pressure
        flowrateVar = 0;
        viscosityVar = 0;
        pressureVar = 0;
        guideStr
    end

    methods (Access = private)
        % find flowrate
        function results = findFlowrate(app,value,variation)
            % getting data from data_sheet_1.mat
            
            if variation == 0
                result = find(app.data.flowrate == app.flowrate);
                results  = app.data.df_n(result,:);
            else
               results = plusOrMinus(app,value,variation,1,app.data.df_n)
            end
        end
        
        function results = findViscocity(app,value,variation)
            if variation == 0
                result = app.currentFlowrateData(:,3)  == value;
                results = app.currentFlowrateData(result,:);
            else
               results = plusOrMinus(app,value,variation,3,app.currentFlowrateData)
            end
%             % getting data from .mat
%             result = find(app.currentFlowrateData(:,3)  == value);
%             results = app.currentFlowrateData(result,:);
        end
    
        function results = findPressure(app,inp)
             if variation == 0
             result = find(app.currentViscocityData(:,4) == inp);
             results = app.currentViscocityData(result,:);
            else
               results = plusOrMinus(app,value,variation,4,app.currentViscocityData)
            end
%            result = find(app.currentViscocityData(:,4) == inp);
%            results = app.currentViscocityData(result,:);
        end 
    
        function results = findFinalIndex(app,value,variation)
             if variation == 0
                 result = find(app.currentViscocityData(:,4) == value);
                 results = app.currentViscocityData(result,:);
             else
               results = plusOrMinus(app,value,variation,4,app.currentViscocityData)
             end
        end
        
        function flowratefunc(app,Data)
            app.currentFlowrateData = Data;
            app.viscosity = Data(1,3);
            app.pressure = Data(1,4);
            
                listData = unique(Data(:,3));
                
                viscodd = cellstr(num2str(listData))';
                app.ViscosityDropDown.Items = viscodd;
                app.ViscosityDropDown.Enable = 'on';
                
                app.DisplayResult.Visible = 'on';
                app.DisplayResult.Data = Data;

                app.userGuideLabel.Text = 'select viscocity';
                app.testLabel.Text = num2str(length(Data'));
                
                [col,~] = size(listData);
                
                f = @pressurefunc;
                
                viscocityfunc(app,Data,f);
        end
    
        function viscocityfunc(app,Data,f)
            app.currentViscocityData = Data;
            
            listData = unique(Data(:,4));
            app.PressurePDropDown.Items = cellstr(num2str(listData))';
            app.PressurePDropDown.Enable = 'on';
            [col , ~] = size(listData);
                f(app,Data);
                app.testLabel.Text = num2str(col);
            app.DisplayResult.Data = Data;
            
        end
    
        function pressurefunc(app,Data)
            app.currentPrassureData = Data;
            app.DisplayResult.Data = Data;
            app.testLabel.Text = 'done';
            index = Data(:,5);
            if ~isempty(index)
                app.userGuideLabel.Text = strcat('Diameter :',{' '},num2str(index(1)));
            end
        end 
       
        function results = plusOrMinus(app,value,variation,colIndex,currentData)
            max =  value + variation;
            min = value - variation;
            if ~isempty(value)
                indexes = find(min < currentData(:,colIndex) & max > currentData(:,colIndex));
                disp(min+" "+max);
                results = currentData(indexes,:);
            else
                results = zeros(0,6);
            end
           
        end


        function totalResults(app,len)       
            app.TotalresultsLabel.Text = strcat('Total results :',{' '},num2str(len));
            app.TotalresultsLabel.Visible = 'on';
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DisplayResult.Visible = 'off';
            app.testLabel.Visible = 'off';
            % disable
            app.ViscosityDropDown.Enable = 'off';
            app.PressurePDropDown.Enable = 'off';
            app.TotalresultsLabel.Visible = 'off';
            % defining user to do
            app.userGuideLabel.Text = 'add Flow rate to find diameter';
                                              
        end

        % Value changed function: FlowrateQEditField
        function FlowrateQEditFieldValueChanged(app, event)
            value = app.FlowrateQEditField.Value;
            app.flowrate = value;
            
            Data = findFlowrate(app,app.flowrate,0);
            totalResults(app,size(Data,1));
            
            app.PressurePDropDown.Items = {['click to select']};
            app.PressurePDropDown.Enable = 'off';
            app.currentPrassureData = [];
            
            app.ViscosityDropDown.Items = {['click to select']};
            app.ViscosityDropDown.Enable = 'off';
            app.currentViscocityData = [];
            
            %app.ViscosityDropDown.Items = 
            if ~isempty(Data) && app.flowrate ~= 0
                % updating current data
                 flowratefunc(app,Data);
                 
            %  for variation input
            DataVar = findFlowrate(app,app.flowrate,app.flowrateVar);
            totalResults(app,size(DataVar,1));
            flowratefunc(app,DataVar);
            else
                app.DisplayResult.Visible = 'off';
                app.ViscosityDropDown.Enable = 'off';
                app.PressurePDropDown.Enable = 'off';
                
                app.userGuideLabel.Text = 'No data Found !';
                
            end            
        end

        % Value changed function: ViscosityDropDown
        function ViscosityDropDownValueChanged(app, event)
            value = str2double(app.ViscosityDropDown.Value);
            app.viscosity = value;
            Data = findViscocity(app,value,0);
            totalResults(app,size(Data,1));
            
            f = @pressurefunc;
            viscocityfunc(app,Data,f);
            
            %  for variation input
             DataVar = findViscocity(app,app.viscosity,app.viscosityVar);
              totalResults(app,size(DataVar,1));
              f = @pressurefunc;
              viscocityfunc(app,DataVar,f);
            
        end

        % Value changed function: PressurePDropDown
        function PressurePDropDownValueChanged(app, event)
            value = str2double(app.PressurePDropDown.Value);
            app.pressure = value;
            % selecting final index value
            Data = findFinalIndex(app,value,0);
            totalResults(app,size(Data,1));
            
            pressurefunc(app,Data);
            
            % for variation input 
            DataVar = findFinalIndex(app,app.pressure,app.pressureVar);
            totalResults(app,size(DataVar,1));
            
            pressurefunc(app,DataVar);
        end

        % Callback function
        function okButtonPushed(app, event)

        end

        % Value changed function: FlowrateVariationEditField
        function FlowrateVariationEditFieldValueChanged(app, event)
            value = app.FlowrateVariationEditField.Value;
            app.flowrateVar = value;
%             Data = plusOrMinus(app,app.flowrate,value,1,app.data.df_n);
%             if ~isempty(Data)
%                 flowratefunc(app,Data);
%             end
            Data = findFlowrate(app,app.flowrate,value);
            totalResults(app,size(Data,1));
            
            flowratefunc(app,Data);
        end

        % Value changed function: ViscosityVariationEditField
        function ViscosityVariationEditFieldValueChanged(app, event)
            value = app.ViscosityVariationEditField.Value;
            app.viscosityVar = value;
%             Data = plusOrMinus(app,app.flowrate,value,3,app.currentFlowrateData);
%             if ~isempty(Data)
%                  flowratefunc(app,Data);
%             end
              Data = findViscocity(app,app.viscosity,value);
              totalResults(app,size(Data,1));
              
              f = @pressurefunc;
              viscocityfunc(app,Data,f);
        end

        % Value changed function: PressureVariationEditField
        function PressureVariationEditFieldValueChanged(app, event)
            value = app.PressureVariationEditField.Value;
           app.pressureVar = value;
%             Data = plusOrMinus(app,app.flowrate,value,4,app.currentViscocityData);
%             if ~isempty(Data)
%                  flowratefunc(app,Data);
%             end
            Data = findFinalIndex(app,app.pressure,value);
            totalResults(app,size(Data,1));
            
            pressurefunc(app,Data);
        end

        % Value changed function: dropdownmodeSwitch
        function dropdownmodeSwitchValueChanged(app, event)
            value = app.dropdownmodeSwitch.Value;
            
            if value == "On"
                app.TextFieldPanel.Visible = "Off";
            else
                app.TextFieldPanel.Visible = "On";
            end
        end

        % Value changed function: ViscosityEditField
        function ViscosityEditFieldValueChanged(app, event)
            value = app.ViscosityEditField.Value;
            app.viscosity = value;
            
            Data = findViscocity(app,value,0);
            totalResults(app,size(Data,1));
            
            f = @pressurefunc;
            viscocityfunc(app,Data,f);
            
             %  for variation input
             DataVar = findViscocity(app,app.viscosity,app.viscosityVar);
              totalResults(app,size(DataVar,1));
              f = @pressurefunc;
              viscocityfunc(app,DataVar,f);
            
        end

        % Value changed function: PressurePEditField
        function PressurePEditFieldValueChanged(app, event)
            value = app.PressurePEditField.Value;
            
             app.pressure = value;
            % selecting final index value
            Data = findFinalIndex(app,value,0);
            totalResults(app,size(Data,1));
            
            pressurefunc(app,Data);
            
             %  for variation input
            DataVar = findFinalIndex(app,app.pressure,app.pressureVar);
            totalResults(app,size(DataVar,1));
            
            pressurefunc(app,DataVar);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DisplayResult = uitable(app.UIFigure);
            app.DisplayResult.ColumnName = {'m3/hr - Q'; '        kW - P'; 'Viscosity'; 'Pressure - bar'; 'Diameter'; 'Pitch'};
            app.DisplayResult.RowName = {};
            app.userGuideLabel = uilabel(app.UIFigure);
            app.userGuideLabel.Text = 'userGuide';
            app.testLabel = uilabel(app.UIFigure);
            app.testLabel.Text = 'test';
            app.Panel = uipanel(app.UIFigure);
            app.FlowrateQEditFieldLabel = uilabel(app.Panel);
            app.FlowrateQEditFieldLabel.Text = 'Flow rate - Q';
            app.FlowrateQEditField = uieditfield(app.Panel, 'numeric');
            app.FlowrateQEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateQEditFieldValueChanged, true);
            app.ViscosityLabel = uilabel(app.Panel);
            app.ViscosityLabel.Text = 'Viscosity - μ ';
            app.ViscosityDropDown = uidropdown(app.Panel);
            app.ViscosityDropDown.Items = {'click to select', 'Option 2', 'Option 3', 'Option 4'};
            app.ViscosityDropDown.ValueChangedFcn = createCallbackFcn(app, @ViscosityDropDownValueChanged, true);
            app.ViscosityDropDown.Value = 'click to select';
            app.PressurePLabel = uilabel(app.Panel);
            app.PressurePLabel.Text = 'Pressure - P ';
            app.PressurePDropDown = uidropdown(app.Panel);
            app.PressurePDropDown.Items = {'click to select', 'Option 2', 'Option 3'};
            app.PressurePDropDown.ValueChangedFcn = createCallbackFcn(app, @PressurePDropDownValueChanged, true);
            app.PressurePDropDown.Value = 'click to select';
            app.RPMDropDownLabel = uilabel(app.Panel);
            app.RPMDropDownLabel.Text = 'RPM            ';
            app.RPMDropDown = uidropdown(app.Panel);
            app.RPMDropDown.Items = {'950'};
            app.RPMDropDown.Value = '950';
            app.FlowrateVariationEditField = uieditfield(app.Panel, 'numeric');
            app.FlowrateVariationEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateVariationEditFieldValueChanged, true);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '±';
            app.ViscosityVariationEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityVariationEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityVariationEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '±';
            app.PressureVariationEditField = uieditfield(app.Panel, 'numeric');
            app.PressureVariationEditField.ValueChangedFcn = createCallbackFcn(app, @PressureVariationEditFieldValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '±';
            app.dropdownmodeSwitch = uiswitch(app.Panel, 'slider');
            app.dropdownmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @dropdownmodeSwitchValueChanged, true);
            app.dropdownmodeSwitchLabel = uilabel(app.Panel);
            app.dropdownmodeSwitchLabel.Text = 'drop down mode';
            app.TextFieldPanel = uipanel(app.Panel);
            app.ViscosityLabel_2 = uilabel(app.TextFieldPanel);
            app.ViscosityLabel_2.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.TextFieldPanel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.TextFieldPanel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.TextFieldPanel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.TotalresultsLabel = uilabel(app.UIFigure);
            app.TotalresultsLabel.Text = 'Total results';
            app.mhrLabel = uilabel(app.UIFigure);
            app.mhrLabel.Text = 'm³/hr';
            app.cStLabel = uilabel(app.UIFigure);
            app.cStLabel.Text = 'cSt';
            app.barLabel = uilabel(app.UIFigure);
            app.barLabel.Text = 'bar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end